classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        VeryloudLamp          matlab.ui.control.Lamp
        VeryloudLampLabel     matlab.ui.control.Label
        NoiseLevelGauge       matlab.ui.control.LinearGauge
        NoiseLevelGaugeLabel  matlab.ui.control.Label
        STATUSLabel           matlab.ui.control.Label
        NormalLamp            matlab.ui.control.Lamp
        NormalLampLabel       matlab.ui.control.Label
        loudLamp              matlab.ui.control.Lamp
        loudLampLabel         matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            api = 'https://datausa.io/api/data?drilldowns=Nation&measures=Population';
            S = webread(api);
            plot(app.UIAxes, [0,1,2,3,4,5,6,7,8,9,10], [0,9,9,9,9,9,3,4,5,6,1])
            
            dbValue = 150;
            app.NoiseLevelGauge.Value = dbValue;
            app.NormalLamp.Color = [0.90, 0.90, 0.90];
            app.loudLamp.Color = [0.90, 0.90, 0.90];
            app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
            if dbValue < 80
                app.NormalLamp.Color = 'green';
            elseif dbValue >= 80 && dbValue < 120
                app.loudLamp.Color = 'yellow';
            elseif dbValue >= 120
                app.VeryloudLamp.Color = 'red';
            end


            % find the serial port connected to the Arduino 
            serialportlist("available");

            % connect to the arduino using serial port and baud rate specified 
            % in arduino code 
            arduinoObj = serialport("COM4",9600);

            % prepare the serial port to start streaming data
            % Set the terminator property to match terminator specified
            % in arduino code

            configureTerminator(arduinoObj,"CR/LF");

            % flush the serial port to remove old data
            flush(arduinoObj);

            % Prepare the UserData property to store arduino data
            % The Data field of the struct saves the sine wave value and 
            % the count field saves the x-axis value of the sine wave
            arduinoObj.UserData = struct("Data",[],"Count",1);

            % create a callback function that reads the first 1000 ASCII 
            % terminated sine wave data points and plots the results

            % Set the BytesAvailableFcnMode property to "terminator"
            % and the BytesAvailableFcnMode property to @readSineWaveData
            % the callback function readSineWaveData is triggered when a
            % new sine wave data (with the terminator) is available to be read
            % from the arduino

            configureCallback(arduinoObj,"terminator",@readSineWaveData);

            function readSineWaveData(src, ~)

                % Read the ASCII data from the serialport object.
                data = readline(src);

                % Convert the string data to numeric type and save it in the UserData
                % property of the serialport object.
                src.UserData.Data(end+1) = str2double(data);

                % Update the Count value of the serialport object.
                src.UserData.Count = src.UserData.Count + 1;

                % If 1001 data points have been collected from the Arduino, switch off the
                % callbacks and plot the data.
                if src.UserData.Count > 1001
                    configureCallback(src, "off");
                    plot(src.UserData.Data(2:end));
                end
    
                if data > 0 && data <= 40
                    app.NormalLamp.Color = [0.00,1.00,0.00]; %green
                elseif data > 40 && data <= 100
                    app.loundLamp.Color = [0.93,0.69,0.13]; %orange
                else 
                    app.VeryloudLamp.Color = [1.00,0.00,0.00]; %red
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            cla(app.UIAxes)
            app.NoiseLevelGauge.Value = 0;
            app.NormalLamp.Color = [0.90, 0.90, 0.90];
            app.loudLamp.Color = [0.90, 0.90, 0.90];
            app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ADC Value ( dB )')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'copper')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.loudLampLabel = uilabel(app.UIFigure);
            app.loudLampLabel.Text = 'loud';
            app.loudLamp = uilamp(app.UIFigure);
            app.NormalLampLabel = uilabel(app.UIFigure);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.UIFigure);
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STATUS';
            app.NoiseLevelGaugeLabel = uilabel(app.UIFigure);
            app.NoiseLevelGaugeLabel.Text = 'Noise Level';
            app.NoiseLevelGauge = uigauge(app.UIFigure, 'linear');
            app.NoiseLevelGauge.Limits = [0 180];
            app.VeryloudLampLabel = uilabel(app.UIFigure);
            app.VeryloudLampLabel.Text = 'Very loud';
            app.VeryloudLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end